classdef AppV2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AneurysmMetricsUIFigure  matlab.ui.Figure
        ImageMetricsLabel        matlab.ui.control.Label
        BVMaxDiameterLabel       matlab.ui.control.Label
        ANMaxDiameterLabel       matlab.ui.control.Label
        SizeRatioLabel           matlab.ui.control.Label
        BVmaxdiam                matlab.ui.control.Label
        Amaxdiam                 matlab.ui.control.Label
        SizeRatio                matlab.ui.control.Label
        BrainSliceLabel          matlab.ui.control.Label
        AneurysmandVesselLabel   matlab.ui.control.Label
        LoadImagesButton         matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            display('Load in the brain')
            [file_brain,path_brain] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_brain,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_brain,file_brain)]);
               imbrain = double(niftiread(fullfile(path_brain,file_brain))); %creates the brain matrix
               imbrainf = fullfile(path_brain,file_brain);
               %imbrain = fullfile(path_brain,file_brain);
               % imbrain = convertCharsToStrings(imbrain); 
               assignin('base','imbrainf',imbrainf);
               assignin('base','imbrain',imbrain); %assigns the variable imbrain (the dowloaded image) as 'imbrain' in the workspace
            end
            
            display('Load in the mask')
            [file_mask,path_mask] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_mask,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_mask,file_mask)]);
               immask = double(niftiread(fullfile(path_mask,file_mask))); %creates the mask matrix
               immaskf = fullfile(path_mask,file_mask);
               %immask = fullfile(path_mask,file_mask);
               %immask = convertCharsToStrings(immask);
               assignin('base','immaskf',immaskf);
               assignin('base','immask',immask); %assigns the variable immask (the dowloaded image) as 'imbrain' in the workspace
            end
            
            
            run('main.m');
            Show = uiaxes(app.AneurysmMetricsUIFigure,"Position", [215 20 319 300]);%coordinates: last 2 are size, 1st is horizontal pos, 2nd is vertical pos
            imshow(BV_AN_Brain,[],'parent',Show);
           
            Show = uiaxes(app.AneurysmMetricsUIFigure,"Position", [485 20 340 306]);%coordinates: last 2 are size, 1st is horizontal pos, 2nd is vertical pos
            imshow(BV_AN_Zoomed,[],'parent',Show);%need to use something other than bob
            
            %app.ImageMetricsListBox.ItemsData = [3, 4, 5];
            %app.BVmaxdiam.Text = 'jennaTest';
           % jennaTest = cast(jennaTest,string);
           
           %jennaTest has to be saved as a string with '' in the matlab
           %code to work this way.
            app.BVmaxdiam.Text = GUIBVLargestLength;
            app.Label.Text = 'mm';
            app.Amaxdiam.Text = GUIANLargestLength;
            app.Label_2.Text = 'mm';
            app.SizeRatio.Text = GUIsizeRatio;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AneurysmMetricsUIFigure = uifigure('Visible', 'off');
            app.AneurysmMetricsUIFigure.Name = 'Aneurysm Metrics';
            app.ImageMetricsLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.ImageMetricsLabel.Tooltip = {'Maximum Diameter of the Blood Vessel attached to the anerusym'};
            app.ImageMetricsLabel.Text = 'Image Metrics:';
            app.BVMaxDiameterLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.BVMaxDiameterLabel.Tooltip = {'Maximum Diameter of the Blood Vessel attached to the anerusym'};
            app.BVMaxDiameterLabel.Text = 'BV. Max. Diameter:';
            app.ANMaxDiameterLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.ANMaxDiameterLabel.Tooltip = {'Maximum Diameter of the Aneurysm'};
            app.ANMaxDiameterLabel.Text = 'AN. Max. Diameter:';
            app.SizeRatioLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.SizeRatioLabel.Tooltip = {'Size Ratio between the Maximum Diameter of the Aneurysm and the Maximum Diameter of the Blood Vessel'};
            app.SizeRatioLabel.Text = 'Size Ratio:';
            app.BVmaxdiam = uilabel(app.AneurysmMetricsUIFigure);
            app.BVmaxdiam.Tooltip = {'Maximum Diameter of the Blood Vessel attached to the anerusym'};
            app.BVmaxdiam.Text = '';
            app.Amaxdiam = uilabel(app.AneurysmMetricsUIFigure);
            app.Amaxdiam.Tooltip = {'Maximum Diameter of the Aneurysm'};
            app.Amaxdiam.Text = '';
            app.SizeRatio = uilabel(app.AneurysmMetricsUIFigure);
            app.SizeRatio.Tooltip = {'Size Ratio between the Maximum Diameter of the Aneurysm and the Maximum Diameter of the Blood Vessel'};
            app.SizeRatio.Text = '';
            app.BrainSliceLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.BrainSliceLabel.Tooltip = {'Brain Slice with aneurysm highlighted neon green. May need to zoom in to see aneurysm.'};
            app.BrainSliceLabel.Text = 'Brain Slice:';
            app.AneurysmandVesselLabel = uilabel(app.AneurysmMetricsUIFigure);
            app.AneurysmandVesselLabel.Tooltip = {'Slice of Aneurysm contining the Maximum Diameter. Anerusym is neon green. Blood Vessel is dull green.'};
            app.AneurysmandVesselLabel.Text = 'Aneurysm and Vessel:';
            app.LoadImagesButton = uibutton(app.AneurysmMetricsUIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Icon = 'matlab go.JPG';
            app.LoadImagesButton.Tooltip = {'Start'};
            app.LoadImagesButton.Text = 'Load Images';
            app.Label = uilabel(app.AneurysmMetricsUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.AneurysmMetricsUIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.AneurysmMetricsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppV2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AneurysmMetricsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AneurysmMetricsUIFigure)
        end
    end
end